classdef ElectroApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_3                       matlab.ui.container.Panel
        ElectroAppTringulodeTresCargasLabel  matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        y5Valor                       matlab.ui.control.NumericEditField
        q1CLabel_6                    matlab.ui.control.Label
        y4Valor                       matlab.ui.control.NumericEditField
        q1CLabel_5                    matlab.ui.control.Label
        y3Valor                       matlab.ui.control.NumericEditField
        q1CLabel_4                    matlab.ui.control.Label
        y2Valor                       matlab.ui.control.NumericEditField
        q1CLabel_3                    matlab.ui.control.Label
        y1Valor                       matlab.ui.control.NumericEditField
        q1CLabel_2                    matlab.ui.control.Label
        Trescargaspuntualesseubicanenlosvrticesdeuntringuloequilteroq1q2positivasyq3negativaLaaplicacincalculaelcampoelctricoalolargodelaalturayeltrabajonecesarioparaensamblarelsistemaapartirdelosvaloresdelascargasyladistanciaentreellasLabel  matlab.ui.control.Label
        aField                        matlab.ui.control.NumericEditField
        amLabel                       matlab.ui.control.Label
        CalculartrabajoButton         matlab.ui.control.Button
        CalcularcampoelectricoButton  matlab.ui.control.Button
        q3Field                       matlab.ui.control.NumericEditField
        q3CLabel                      matlab.ui.control.Label
        q2Field                       matlab.ui.control.NumericEditField
        q2CLabel                      matlab.ui.control.Label
        q1Field                       matlab.ui.control.NumericEditField
        q1CLabel                      matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Panel                         matlab.ui.container.Panel
        CamposRepuesta                matlab.ui.control.TextArea
        ResultadosdelcampoelctricoLabel  matlab.ui.control.Label
        ResultadoLabel                matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularcampoelectricoButton
        function CalcularcampoelectricoButtonPushed(app, event)


        % ----- Obtener valores (en µC, convertir a C) -----
        q1 = double(app.q1Field.Value) * 1e-6;  % Carga 1 en Coulombs
        q2 = double(app.q2Field.Value) * 1e-6;  % Carga 2 en Coulombs
        q3 = double(app.q3Field.Value)  * 1e-6;  % Carga 3 en Coulombs
        
        % ----- Parámetros del sistema -----
        a = double(app.aField.Value);            % Lado del triángulo equilátero en metros
        ke = 9e9;                                % Constante de Coulomb [N·m^2/C^2]
        
        % coordenadas de y
        y_vals = zeros(1,5);
        y_vals(1) =  double(app.y1Valor.Value);
        y_vals(2) =  double(app.y2Valor.Value);
        y_vals(3) =  double(app.y3Valor.Value);
        y_vals(4) =  double(app.y4Valor.Value);
        y_vals(5) =  double(app.y5Valor.Value);

        % Prealocar vector de resultados
        E_vals = zeros(size(y_vals));

    % ----- Cálculo de |E| para cada punto -----
    for i = 1:length(y_vals)
        y = y_vals(i);
        Px = a/2; 
        Py = y;
        
        % Distancias desde P a cada carga
        r1 = sqrt((Px-0)^2 + (Py-0)^2);   % P a q1
        r2 = sqrt((Px-a)^2 + (Py-0)^2);   % P a q2
        r3 = abs(Py-a);                   % P a q3 (vertical)
        
        % Vectores unitarios
        u1 = [(Px-0) (Py-0)]/r1;
        u2 = [(Px-a) (Py-0)]/r2;
        u3 = [0 (Py-a)]/r3;
        
        % Campos individuales
        E1 = ke*q1/r1^2 * u1;
        E2 = ke*q2/r2^2 * u2;
        E3 = ke*q3/r3^2 * u3;
        
        % Campo total
        Etot = E1 + E2 + E3;
        E_vals(i) = norm(Etot);
    end
    
        % Mostrar resultado
        fprintf('Punto P = (%.3f , %.3f) m -> |E| = %.3e N/C\n',Px,Py,E_vals(i))
        % Crear el texto línea por línea
        texto = sprintf('1-Campo |E| %.3e [N/C]   Coordenada y %.3f [m]\n', E_vals(1), y_vals(1));
        texto = [texto sprintf('2-Campo |E| %.3e [N/C]   Coordenada y %.3f [m]\n', E_vals(2), y_vals(2))];
        texto = [texto sprintf('3-Campo |E| %.3e [N/C]   Coordenada y %.3f [m]\n', E_vals(3), y_vals(3))];
        texto = [texto sprintf('4-Campo |E| %.3e [N/C]   Coordenada y %.3f [m]\n', E_vals(4), y_vals(4))];
        texto = [texto sprintf('5-Campo |E| %.3e [N/C]   Coordenada y %.3f [m]', E_vals(5), y_vals(5))];
        
        app.CamposRepuesta.Value = texto;
        % ----- Gráfico -----
            
        % Graficar los resultados
        plot(app.UIAxes, y_vals, E_vals, 'y--', 'LineWidth', 2,'MarkerSize',6);
        xlabel(app.UIAxes, 'Coordenada y [m]');
        ylabel(app.UIAxes, 'Campo |E| [N/C]');
        
        % Crear el título con tamaño de fuente más pequeño
        titulo = title(app.UIAxes, 'Magnitud del campo eléctrico en el punto P a lo largo de la altura');
        titulo.FontSize = 10; % Ajusta este valor según lo que necesites
        
        grid(app.UIAxes, 'on');


        end

        % Button pushed function: CalculartrabajoButton
        function CalculartrabajoButtonPushed(app, event)
            q1 = app.q1Field.Value * 1e-6;
            q2 = app.q2Field.Value * 1e-6;
            q3 = app.q3Field.Value * 1e-6;
            a  = app.aField.Value; % metros

            % Llamar al script del trabajo
            W = trabajoCargas(q1, q2, q3, a);
        
            % Mostrar resultado 
           app.ResultadoLabel.Text = ['Trabajo: ' num2str(W) ' J'];

             % gráfica
            k = 9e9;
            a_values = linspace(0.05, 1, 100); % de 5 cm a 1 m
            W_values = k * (q1*q2 + q1*q3 + q2*q3) ./ a_values;
        
            % Dibujar en el UIAxes
            plot(app.UIAxes, a_values, W_values, 'r-', 'LineWidth', 2);
            xlabel(app.UIAxes, 'Distancia a (m)');
            ylabel(app.UIAxes, 'Trabajo W (J)');
            title(app.UIAxes, 'Trabajo en función de la distancia a');
            grid(app.UIAxes, 'on');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.SubtitleFontWeight = 'bold';
            app.ResultadoLabel = uilabel(app.Panel);
            app.ResultadoLabel.Text = 'Trabajo (W): 0,00 J';
            app.ResultadosdelcampoelctricoLabel = uilabel(app.Panel);
            app.ResultadosdelcampoelctricoLabel.Text = 'Campo en función de la distancia a (∣E∣):';
            app.CamposRepuesta = uitextarea(app.Panel);
            app.Panel_2 = uipanel(app.UIFigure);
            app.Image = uiimage(app.Panel_2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'imagen.png');
            app.q1CLabel = uilabel(app.Panel_2);
            app.q1CLabel.Text = 'q1 (µC):';
            app.q1Field = uieditfield(app.Panel_2, 'numeric');
            app.q1Field.Value = 2;
            app.q2CLabel = uilabel(app.Panel_2);
            app.q2CLabel.Text = 'q2 (µC) :';
            app.q2Field = uieditfield(app.Panel_2, 'numeric');
            app.q2Field.Value = 2;
            app.q3CLabel = uilabel(app.Panel_2);
            app.q3CLabel.Text = 'q3 (µC): ';
            app.q3Field = uieditfield(app.Panel_2, 'numeric');
            app.q3Field.Value = -2;
            app.CalcularcampoelectricoButton = uibutton(app.Panel_2, 'push');
            app.CalcularcampoelectricoButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularcampoelectricoButtonPushed, true);
            app.CalcularcampoelectricoButton.Text = 'Calcular campo electrico';
            app.CalculartrabajoButton = uibutton(app.Panel_2, 'push');
            app.CalculartrabajoButton.ButtonPushedFcn = createCallbackFcn(app, @CalculartrabajoButtonPushed, true);
            app.CalculartrabajoButton.Text = 'Calcular trabajo';
            app.amLabel = uilabel(app.Panel_2);
            app.amLabel.Text = 'a (m): ';
            app.aField = uieditfield(app.Panel_2, 'numeric');
            app.aField.Value = 0.1;
            app.Trescargaspuntualesseubicanenlosvrticesdeuntringuloequilteroq1q2positivasyq3negativaLaaplicacincalculaelcampoelctricoalolargodelaalturayeltrabajonecesarioparaensamblarelsistemaapartirdelosvaloresdelascargasyladistanciaentreellasLabel = uilabel(app.Panel_2);
            app.Trescargaspuntualesseubicanenlosvrticesdeuntringuloequilteroq1q2positivasyq3negativaLaaplicacincalculaelcampoelctricoalolargodelaalturayeltrabajonecesarioparaensamblarelsistemaapartirdelosvaloresdelascargasyladistanciaentreellasLabel.Text = '"Tres cargas puntuales se ubican en los vértices de un triángulo equilátero ( q1,q2: positivas y q3 negativa). La aplicación calcula el campo eléctrico a lo largo de la altura y el trabajo necesario para ensamblar el sistema, a partir de los valores de las cargas y la distancia entre ellas."';
            app.q1CLabel_2 = uilabel(app.Panel_2);
            app.q1CLabel_2.Text = 'y1';
            app.y1Valor = uieditfield(app.Panel_2, 'numeric');
            app.y1Valor.Value = 0.01;
            app.q1CLabel_3 = uilabel(app.Panel_2);
            app.q1CLabel_3.Text = 'y2';
            app.y2Valor = uieditfield(app.Panel_2, 'numeric');
            app.y2Valor.Value = 0.02;
            app.q1CLabel_4 = uilabel(app.Panel_2);
            app.q1CLabel_4.Text = 'y3';
            app.y3Valor = uieditfield(app.Panel_2, 'numeric');
            app.y3Valor.Value = 0.03;
            app.q1CLabel_5 = uilabel(app.Panel_2);
            app.q1CLabel_5.Text = 'y4';
            app.y4Valor = uieditfield(app.Panel_2, 'numeric');
            app.q1CLabel_6 = uilabel(app.Panel_2);
            app.q1CLabel_6.Text = 'y5';
            app.y5Valor = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.UIFigure);
            app.ElectroAppTringulodeTresCargasLabel = uilabel(app.Panel_3);
            app.ElectroAppTringulodeTresCargasLabel.Text = 'ElectroApp: Triángulo de Tres Cargas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElectroApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end